classdef bh_robx_demo_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure_ROBX_DEMO_SELECTOR     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_DEMO_4_LINK                 matlab.ui.container.Tab
        LabelDiscreteKnob               matlab.ui.control.Label
        DiscreteKnob_DEMO_WHEEL_4_LINK  matlab.ui.control.DiscreteKnob
        Tab_TIDY                        matlab.ui.container.Tab
        Button_CLEAR_BASE_WKSPACE       matlab.ui.control.Button
        LabelListBox                    matlab.ui.control.Label
        ListBox_BASE_WKSPACE            matlab.ui.control.ListBox
        Button_CLOSE_EDITOR             matlab.ui.control.Button
        Button_CLOSE_MODELS             matlab.ui.control.Button
        Tab_PRES                        matlab.ui.container.Tab
        Button_OPEN_PRES                matlab.ui.control.Button
        Button_PC_WARMUP                matlab.ui.control.Button
        Button_MODELS_ON_SCREEN         matlab.ui.control.Button
        Tab2                            matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        Tab4                            matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        Lamp_SLCD                       matlab.ui.control.Lamp
        Label2                          matlab.ui.control.Label
        Lamp_CST                        matlab.ui.control.Lamp
        Label3                          matlab.ui.control.Label
        Lamp_OPT                        matlab.ui.control.Lamp
        Label4                          matlab.ui.control.Label
        Lamp_SYMT                       matlab.ui.control.Lamp
        Label5                          matlab.ui.control.Label
        Lamp_ML                         matlab.ui.control.Lamp
        Label6                          matlab.ui.control.Label
        Lamp_SL                         matlab.ui.control.Lamp
        Button_PROD_CHECK               matlab.ui.control.Button
        Label7                          matlab.ui.control.Label
        Lamp_SC                         matlab.ui.control.Lamp
        Label8                          matlab.ui.control.Label
        Lamp_SCMB                       matlab.ui.control.Lamp
        TextArea3                       matlab.ui.control.TextArea
        DoyouhavetherequiredproductsLabel  matlab.ui.control.Label
        StateflowLabel                  matlab.ui.control.Label
        Lamp_SF                         matlab.ui.control.Lamp
        Tab_DEMO_3_LINK                 matlab.ui.container.Tab
        Label9                          matlab.ui.control.Label
        DiscreteKnob_DEMO_WHEEL_3_LINK  matlab.ui.control.DiscreteKnob
        Tab5                            matlab.ui.container.Tab
        Label10                         matlab.ui.control.Label
        DiscreteKnob_DEMO_WHEEL_2_LINK  matlab.ui.control.DiscreteKnob
        Tab6                            matlab.ui.container.Tab
        TextArea4                       matlab.ui.control.TextArea
        Label11                         matlab.ui.control.Label
        DiscreteKnob_CLASSES            matlab.ui.control.DiscreteKnob
        Tab_HELLO                       matlab.ui.container.Tab
        UIAxes_HELLO                    matlab.ui.control.UIAxes
        TextArea                        matlab.ui.control.TextArea
        ListBox_FUND_SYM_PATTERNS       matlab.ui.control.ListBox
        TAB_COMP_THINKING               matlab.ui.container.Tab
        DEMOSLabel                      matlab.ui.control.Label
        DiscreteKnob_DEMO_WHEEL_CT      matlab.ui.control.DiscreteKnob
        Button_CT_PRES                  matlab.ui.control.Button
        Button_CT_RIGID_PRES            matlab.ui.control.Button
        Tab_TRICKS                      matlab.ui.container.Tab
        DiscreteKnob_DEMO_WHEEL_TRICKS  matlab.ui.control.DiscreteKnob
        LINK_NPTab                      matlab.ui.container.Tab
        Knob_2dof_NP                    matlab.ui.control.DiscreteKnob
        TextArea2                       matlab.ui.control.TextArea
    end


    properties (Access = public)
        OBJ_bh_robx_DS % bh_robx_demo_selector_CLS
    end

    methods (Access = public)
        
        function do_product_check(app)
            DEFAULT_COLOR = [255 191 0]/255; % orange
   
            app.Lamp_SLCD.Color = DEFAULT_COLOR;
            app.Lamp_CST.Color  = DEFAULT_COLOR;
            app.Lamp_OPT.Color  = DEFAULT_COLOR;
            app.Lamp_SYMT.Color = DEFAULT_COLOR;
            app.Lamp_ML.Color   = DEFAULT_COLOR;
            app.Lamp_SL.Color   = DEFAULT_COLOR;
            app.Lamp_SC.Color   = DEFAULT_COLOR;
            app.Lamp_SCMB.Color = DEFAULT_COLOR;
            app.Lamp_SF.Color   = DEFAULT_COLOR;
            
            % just pause for a few seconds
            pause(2);
            
            % what products do you have
            v_S          = ver;
            prod_list_CE = {v_S.Name}';
            
            % update the lamps
            my_database_CE = ...
                { ...
                  'Simulink Control Design',      'Lamp_SLCD';
                  'Control System Toolbox',       'Lamp_CST';
                  'Optimization Toolbox',         'Lamp_OPT';
                  'Symbolic Math Toolbox',        'Lamp_SYMT';
                  'MATLAB',                       'Lamp_ML';
                  'Simulink',                     'Lamp_SL';
                  'Simscape',                     'Lamp_SC';
                  'Simscape Multibody',           'Lamp_SCMB';
                  'Stateflow',                    'Lamp_SF';
                  };
              NUM_LAMPS = size(my_database_CE,1);
              for kk=1:NUM_LAMPS
                  the_prod_str  = my_database_CE{kk,1};
                  the_field_str = my_database_CE{kk,2};
                  tf_i_have_prod = strcmp(prod_list_CE, the_prod_str);
                  
                  if(1==nnz(tf_i_have_prod))
                      app.(the_field_str).Color = 'green';
                  else
                      app.(the_field_str).Color = 'red';
                  end
                  
              end % for
        end % function do_product_check(app)

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OBJ_bh_robx_DS = bh_robx_demo_selector_CLS();
            
            app.UIFigure_ROBX_DEMO_SELECTOR.Tag = 'UIFigure_ROBX_DEMO_SELECTOR';
            
            % plot HELLO
            app.OBJ_bh_robx_DS.plot_hello(app.UIAxes_HELLO);
            
            % reorder the TABS
            app.TabGroup.Children = app.TabGroup.Children([8 6 5 1 7 9 10 11 2 4 3]);
            
            % specify the selected tab
            app.TabGroup.SelectedTab = app.Tab_HELLO;
            
        end

        % Button pushed function: Button_CLEAR_BASE_WKSPACE
        function Button_CLEAR_BASE_WKSPACEButtonPushed(app, event)
            app.OBJ_bh_robx_DS.clear_base();
            A = evalin('base', 'whos');
            names_CE = {A.name}';
            if(0==length(names_CE))
                names_CE = {''};
            end
            app.ListBox_BASE_WKSPACE.Items = names_CE;
        end

        % Button pushed function: Button_CLOSE_EDITOR
        function Button_CLOSE_EDITORButtonPushed(app, event)
             app.OBJ_bh_robx_DS.close_editor();
        end

        % Button pushed function: Button_CLOSE_MODELS
        function Button_CLOSE_MODELSButtonPushed(app, event)
            bdclose('all');
        end

        % Button pushed function: Button_CT_PRES
        function Button_CT_PRESPushed(app, event)
            app.OBJ_bh_robx_DS.task_ct_open_pres();
        end

        % Button pushed function: Button_CT_RIGID_PRES
        function Button_CT_RIGID_PRESPushed(app, event)
            app.OBJ_bh_robx_DS.task_ct_open_rbd_pres();
        end

        % Button pushed function: Button_MODELS_ON_SCREEN
        function Button_MODELS_ON_SCREENButtonPushed(app, event)
             app.OBJ_bh_robx_DS.position_all_MD_on_screen();
        end

        % Button pushed function: Button_OPEN_PRES
        function Button_OPEN_PRESButtonPushed(app, event)
            app.OBJ_bh_robx_DS.open_pres();
        end

        % Button pushed function: Button_PC_WARMUP
        function Button_PC_WARMUPButtonPushed(app, event)
            app.OBJ_bh_robx_DS.do_pc_warmup();
        end

        % Button pushed function: Button_PROD_CHECK
        function Button_PROD_CHECKButtonPushed(app, event)
            app.do_product_check();
        end

        % Value changed function: DiscreteKnob_CLASSES
        function DiscreteKnob_CLASSESValueChanged(app, event)
            value = app.DiscreteKnob_CLASSES.Value;
            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'SHOW_ME'}
                        app.OBJ_bh_robx_DS.show_classes();                 
                otherwise                  
                    error('###_ERRRO: UNknown DEMO wheel choice !');
            end
            
            A = evalin('base', 'whos');
            names_CE = {A.name}';
            if(0==length(names_CE))
                names_CE = {''};
            end
            app.ListBox_BASE_WKSPACE.Items = names_CE;                 
        end

        % Value changed function: DiscreteKnob_DEMO_WHEEL_2_LINK
        function DiscreteKnob_DEMO_WHEEL_2_LINKValueChanged(app, event)
            value = app.DiscreteKnob_DEMO_WHEEL_2_LINK.Value;
            
            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'NEWTON_EOM'}
                        app.OBJ_bh_robx_DS.task_2_link_newton_derivation();
               case {'LAGRANGE_MANUAL_EOM'}
                        app.OBJ_bh_robx_DS.task_2_link_lagrange_derivation_manual();                            
                case {'LAGRANGE_EOM'}
                        app.OBJ_bh_robx_DS.task_2_link_lagrange_derivation();
                case {'MODEL_NEWTON'}
                        app.OBJ_bh_robx_DS.task_2_link_newton_model();
                case {'MODEL_LAGRANGE_MANUAL'}
                        app.OBJ_bh_robx_DS.task_2_link_lagrange_manual_model();
                case {'MODEL_LAGRANGE'}
                        app.OBJ_bh_robx_DS.task_2_link_lagrange_model();                                             
                case {'RATE_JACOBIAN'}
                        app.OBJ_bh_robx_DS.task_2_link_rate_jacob_derivation();         
                case {'MODEL_RATE_JACOBIAN'}
                        app.OBJ_bh_robx_DS.task_2_link_rate_jacob_model();
                otherwise                  
                    error('###_ERRRO: UNknown DEMO wheel choice !');
            end
            
            A = evalin('base', 'whos');
            names_CE = {A.name}';
            if(0==length(names_CE))
                names_CE = {''};
            end
            app.ListBox_BASE_WKSPACE.Items = names_CE;                
        end

        % Value changed function: DiscreteKnob_DEMO_WHEEL_3_LINK
        function DiscreteKnob_DEMO_WHEEL_3_LINKValueChanged(app, event)
            value = app.DiscreteKnob_DEMO_WHEEL_3_LINK.Value;
            
            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'LAGRANGE_EOM'}
                        app.OBJ_bh_robx_DS.task_3_link_lagrange_derivation();
                case {'MODEL_EOM'}
                        app.OBJ_bh_robx_DS.task_3_link_lagrange_model();
                case {'RATE_JACOBIAN'}
                        app.OBJ_bh_robx_DS.task_3_link_rate_jacob_derivation();         
                case {'MODEL_RATE_JACOBIAN'}
                        app.OBJ_bh_robx_DS.task_3_link_rate_jacob_model();
                otherwise                  
                    error('###_ERRRO: UNknown DEMO wheel choice !');
            end
            
            A = evalin('base', 'whos');
            names_CE = {A.name}';
            if(0==length(names_CE))
                names_CE = {''};
            end
            app.ListBox_BASE_WKSPACE.Items = names_CE;           
        end

        % Value changed function: DiscreteKnob_DEMO_WHEEL_4_LINK
        function DiscreteKnob_DEMO_WHEEL_4_LINKValueChanged(app, event)
            value = app.DiscreteKnob_DEMO_WHEEL_4_LINK.Value;
           
            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'LAGRANGE_EOM'}
                        app.OBJ_bh_robx_DS.task_4_link_lagrange_derivation();
                case {'MODEL_EOM'}
                        app.OBJ_bh_robx_DS.task_4_link_lagrange_model();
                case {'RATE_JACOBIAN'}
                        app.OBJ_bh_robx_DS.task_4_link_rate_jacob_derivation();         
                case {'MODEL_RATE_JACOBIAN'}
                        app.OBJ_bh_robx_DS.task_4_link_rate_jacob_model();
                case {'CONTROL_IDEAL_ACTS'}
                        app.OBJ_bh_robx_DS.task_4_link_CONTROL_DL();
                case {'CONTROL_TQ_FFWD'}
                        app.OBJ_bh_robx_DS.task_4_link_CONTROL_TQ_FFWD();                        
                case {'INVERSE_KINEMATICS'}
                        app.OBJ_bh_robx_DS.task_4_link_invkin_via_optim(); 
                case {'LINEARISE_THE_MODEL'}
                        app.OBJ_bh_robx_DS.task_4_link_LINEARISE_the_model();
                otherwise                  
                    error('###_ERRRO: UNknown DEMO wheel choice !');
            end
            
            A = evalin('base', 'whos');
            names_CE = {A.name}';
            if(0==length(names_CE))
                names_CE = {''};
            end
            app.ListBox_BASE_WKSPACE.Items = names_CE;
        end

        % Value changed function: DiscreteKnob_DEMO_WHEEL_CT
        function DiscreteKnob_DEMO_WHEEL_CTValueChanged(app, event)
            value = app.DiscreteKnob_DEMO_WHEEL_CT.Value;
                       
            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
               case {'SMD_EOM'}
                        app.OBJ_bh_robx_DS.task_ct_smd_derivation();                            
                case {'AUTOMATE_EOM'}
                        app.OBJ_bh_robx_DS.task_ct_automate();
                case {'4_LINK_EOM'}
                        app.OBJ_bh_robx_DS.task_ct_4_link_derivation();
                case {'HELLO_ROBOT'}
                        app.OBJ_bh_robx_DS.task_ct_hello_robot();
                
                otherwise                  
                    error('###_ERRRO: UNknown DEMO wheel choice !');
            end
            
            A = evalin('base', 'whos');
            names_CE = {A.name}';
            if(0==length(names_CE))
                names_CE = {''};
            end
            app.ListBox_BASE_WKSPACE.Items = names_CE;                       
            
            
        end

        % Value changed function: DiscreteKnob_DEMO_WHEEL_TRICKS
        function DiscreteKnob_DEMO_WHEEL_TRICKSValueChanged(app, event)
            value = app.DiscreteKnob_DEMO_WHEEL_TRICKS.Value;
                                  
            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
               case {'SWINGUP_EXPLORE'}
                        app.OBJ_bh_robx_DS.task_tricks_swingup_explore();                            
                case {'SWINGUP_PROTO_MODEL'}
                        app.OBJ_bh_robx_DS.task_tricks_swingup_model();
                case {'BALANCE_EXPLORE'}
                        app.OBJ_bh_robx_DS.task_tricks_balance_explore();
                case {'BALANCE_PROTO_MODEL'}
                        app.OBJ_bh_robx_DS.task_tricks_balance_model();
                case {'ROBOT_BALANCE_MODEL'}
                        app.OBJ_bh_robx_DS.task_tricks_robot_balance();                
                otherwise                  
                    error('###_ERROR: UNknown DEMO wheel choice !');
            end            
        end

        % Value changed function: Knob_2dof_NP
        function Knob_2dof_NPValueChanged(app, event)
            value = app.Knob_2dof_NP.Value;
            
            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
               case {'LAGRANGE_EOM'}
                        app.OBJ_bh_robx_DS.task_2dof_np_lagrange_derivation();                            
                case {'MODEL_LAGRANGE'}
                        app.OBJ_bh_robx_DS.task_2dof_np_lagrange_model();
                case {'CONTROLLED'}
                        app.OBJ_bh_robx_DS.task_2dof_np_controlled_model();                                               
                case {'ELECTROMECH_SYSTEM'}
                        app.OBJ_bh_robx_DS.task_2dof_np_electromech_sys_model();        
                otherwise                  
                    error('###_ERROR: UNknown DEMO wheel choice !');
            end                        
        end

        % Value changed function: ListBox_FUND_SYM_PATTERNS
        function ListBox_FUND_SYM_PATTERNSValueChanged(app, event)
            value = app.ListBox_FUND_SYM_PATTERNS.Value;
            switch(upper(value))
                case {'NULL'}
                        % do NOTHING
                case {'SHORT INTRO'}
                        app.OBJ_bh_robx_DS.task_patterns_short_intro();       
                case {'EXPLORE DEEPER'}
                        app.OBJ_bh_robx_DS.task_patterns_explore_deeper();
            
                otherwise                  
                    error('###_ERRRO: UNknown DEMO wheel choice !');
            end
        end

        % Value changed function: ListBox_REPORTS
        function ListBox_REPORTSValueChanged(app, event)
            value = app.ListBox_REPORTS.Value;
            value = upper(value);
            if(strcmp(value,'-----') )
                return
            end
            app.OBJ_bh_robx_DS.open_report(value)
        end

        % Value changed function: ListBox_TEACH_RESOURCES
        function ListBox_TEACH_RESOURCESValueChanged(app, event)
            value = app.ListBox_TEACH_RESOURCES.Value;
            app.OBJ_bh_robx_DS.open_resources(value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure_ROBX_DEMO_SELECTOR = uifigure;
            app.UIFigure_ROBX_DEMO_SELECTOR.Name = 'Robx DEMO';
            app.TabGroup = uitabgroup(app.UIFigure_ROBX_DEMO_SELECTOR);
            app.Tab_DEMO_4_LINK = uitab(app.TabGroup);
            app.Tab_DEMO_4_LINK.Title = '4_LINK';
            app.LabelDiscreteKnob = uilabel(app.Tab_DEMO_4_LINK);
            app.LabelDiscreteKnob.Text = 'DEMOS';
            app.DiscreteKnob_DEMO_WHEEL_4_LINK = uiknob(app.Tab_DEMO_4_LINK, 'discrete');
            app.DiscreteKnob_DEMO_WHEEL_4_LINK.Items = {'OFF', 'Lagrange_EOM', 'Model_EOM', 'Rate_Jacobian', 'Model_Rate_Jacobian', 'Inverse_Kinematics', 'Control_IDEAL_Acts', 'Control_TQ_FFWD', 'Linearise_the_model'};
            app.DiscreteKnob_DEMO_WHEEL_4_LINK.ValueChangedFcn = createCallbackFcn(app, @DiscreteKnob_DEMO_WHEEL_4_LINKValueChanged, true);
            app.DiscreteKnob_DEMO_WHEEL_4_LINK.Value = 'OFF';
            app.Tab_TIDY = uitab(app.TabGroup);
            app.Tab_TIDY.Title = 'TIDY';
            app.Button_CLEAR_BASE_WKSPACE = uibutton(app.Tab_TIDY, 'push');
            app.Button_CLEAR_BASE_WKSPACE.ButtonPushedFcn = createCallbackFcn(app, @Button_CLEAR_BASE_WKSPACEButtonPushed, true);
            app.Button_CLEAR_BASE_WKSPACE.Text = 'Clear BASE workspace';
            app.LabelListBox = uilabel(app.Tab_TIDY);
            app.LabelListBox.Text = 'Workspace';
            app.ListBox_BASE_WKSPACE = uilistbox(app.Tab_TIDY);
            app.Button_CLOSE_EDITOR = uibutton(app.Tab_TIDY, 'push');
            app.Button_CLOSE_EDITOR.ButtonPushedFcn = createCallbackFcn(app, @Button_CLOSE_EDITORButtonPushed, true);
            app.Button_CLOSE_EDITOR.Text = 'Close Editor';
            app.Button_CLOSE_MODELS = uibutton(app.Tab_TIDY, 'push');
            app.Button_CLOSE_MODELS.ButtonPushedFcn = createCallbackFcn(app, @Button_CLOSE_MODELSButtonPushed, true);
            app.Button_CLOSE_MODELS.Text = 'Close all Models ';
            app.Tab_PRES = uitab(app.TabGroup);
            app.Tab_PRES.Title = 'PRES';
            app.Button_OPEN_PRES = uibutton(app.Tab_PRES, 'push');
            app.Button_OPEN_PRES.ButtonPushedFcn = createCallbackFcn(app, @Button_OPEN_PRESButtonPushed, true);
            app.Button_OPEN_PRES.Text = 'Open Presentation';
            app.Button_PC_WARMUP = uibutton(app.Tab_PRES, 'push');
            app.Button_PC_WARMUP.ButtonPushedFcn = createCallbackFcn(app, @Button_PC_WARMUPButtonPushed, true);
            app.Button_PC_WARMUP.Text = 'PC Warmup';
            app.Button_MODELS_ON_SCREEN = uibutton(app.Tab_PRES, 'push');
            app.Button_MODELS_ON_SCREEN.ButtonPushedFcn = createCallbackFcn(app, @Button_MODELS_ON_SCREENButtonPushed, true);
            app.Button_MODELS_ON_SCREEN.Text = 'Models on Screen';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'SYSTEM_CHECK';
            app.TabGroup2 = uitabgroup(app.Tab2);
            app.Tab4 = uitab(app.TabGroup2);
            app.Tab4.Title = 'PRODUCTS';
            app.Label = uilabel(app.Tab4);
            app.Label.Text = 'Simulink Control Design';
            app.Lamp_SLCD = uilamp(app.Tab4);
            app.Label2 = uilabel(app.Tab4);
            app.Label2.Text = 'Control System Toolbox';
            app.Lamp_CST = uilamp(app.Tab4);
            app.Label3 = uilabel(app.Tab4);
            app.Label3.Text = 'Optimization Toolbox';
            app.Lamp_OPT = uilamp(app.Tab4);
            app.Label4 = uilabel(app.Tab4);
            app.Label4.Text = 'Symbolic Math Toolbox';
            app.Lamp_SYMT = uilamp(app.Tab4);
            app.Label5 = uilabel(app.Tab4);
            app.Label5.Text = 'MATLAB';
            app.Lamp_ML = uilamp(app.Tab4);
            app.Label6 = uilabel(app.Tab4);
            app.Label6.Text = 'Simulink';
            app.Lamp_SL = uilamp(app.Tab4);
            app.Button_PROD_CHECK = uibutton(app.Tab4, 'push');
            app.Button_PROD_CHECK.ButtonPushedFcn = createCallbackFcn(app, @Button_PROD_CHECKButtonPushed, true);
            app.Button_PROD_CHECK.Text = 'CLICK ME to test your intsallation';
            app.Label7 = uilabel(app.Tab4);
            app.Label7.Text = 'Simscape';
            app.Lamp_SC = uilamp(app.Tab4);
            app.Label8 = uilabel(app.Tab4);
            app.Label8.Text = 'Simscape Multibody';
            app.Lamp_SCMB = uilamp(app.Tab4);
            app.TextArea3 = uitextarea(app.Tab4);
            app.TextArea3.Value = {'If you see any RED lamps, you will not be able to run the demos.'};
            app.DoyouhavetherequiredproductsLabel = uilabel(app.Tab4);
            app.DoyouhavetherequiredproductsLabel.Text = 'Do you have the required products ?';
            app.StateflowLabel = uilabel(app.Tab4);
            app.StateflowLabel.Text = 'Stateflow';
            app.Lamp_SF = uilamp(app.Tab4);
            app.Tab_DEMO_3_LINK = uitab(app.TabGroup);
            app.Tab_DEMO_3_LINK.Title = '3_LINK';
            app.Label9 = uilabel(app.Tab_DEMO_3_LINK);
            app.Label9.Text = 'DEMOS';
            app.DiscreteKnob_DEMO_WHEEL_3_LINK = uiknob(app.Tab_DEMO_3_LINK, 'discrete');
            app.DiscreteKnob_DEMO_WHEEL_3_LINK.Items = {'OFF', 'Lagrange_EOM', 'Model_EOM', 'Rate_Jacobian', 'Model_Rate_Jacobian'};
            app.DiscreteKnob_DEMO_WHEEL_3_LINK.ValueChangedFcn = createCallbackFcn(app, @DiscreteKnob_DEMO_WHEEL_3_LINKValueChanged, true);
            app.DiscreteKnob_DEMO_WHEEL_3_LINK.Value = 'OFF';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = '2_LINK';
            app.Label10 = uilabel(app.Tab5);
            app.Label10.Text = 'DEMOS';
            app.DiscreteKnob_DEMO_WHEEL_2_LINK = uiknob(app.Tab5, 'discrete');
            app.DiscreteKnob_DEMO_WHEEL_2_LINK.Items = {'OFF', 'Newton_EOM', 'Lagrange_manual_EOM', 'Lagrange_EOM', 'Model_Newton', 'Model_Lagrange_manual', 'Model_Lagrange', 'Rate_Jacobian', 'Model_Rate_Jacobian'};
            app.DiscreteKnob_DEMO_WHEEL_2_LINK.ValueChangedFcn = createCallbackFcn(app, @DiscreteKnob_DEMO_WHEEL_2_LINKValueChanged, true);
            app.DiscreteKnob_DEMO_WHEEL_2_LINK.Value = 'OFF';
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'CLASSES';
            app.TextArea4 = uitextarea(app.Tab6);
            app.TextArea4.Value = {'Have a look at how we automated the application of Lagrange''s equation:'};
            app.Label11 = uilabel(app.Tab6);
            app.Label11.Text = 'CLASS FILES';
            app.DiscreteKnob_CLASSES = uiknob(app.Tab6, 'discrete');
            app.DiscreteKnob_CLASSES.Items = {'OFF', 'Show_me'};
            app.DiscreteKnob_CLASSES.ValueChangedFcn = createCallbackFcn(app, @DiscreteKnob_CLASSESValueChanged, true);
            app.DiscreteKnob_CLASSES.Value = 'OFF';
            app.Tab_HELLO = uitab(app.TabGroup);
            app.Tab_HELLO.Title = 'HELLO';
            app.UIAxes_HELLO = uiaxes(app.Tab_HELLO);
            title(app.UIAxes_HELLO, 'Title')
            xlabel(app.UIAxes_HELLO, 'X')
            ylabel(app.UIAxes_HELLO, 'Y')
            app.TextArea = uitextarea(app.Tab_HELLO);
            app.TextArea.Value = {'Review some Fundamental SYMBOLIC computing patterns'};
            app.ListBox_FUND_SYM_PATTERNS = uilistbox(app.Tab_HELLO);
            app.ListBox_FUND_SYM_PATTERNS.Items = {'NULL', 'SHORT intro', 'EXPLORE deeper'};
            app.ListBox_FUND_SYM_PATTERNS.ValueChangedFcn = createCallbackFcn(app, @ListBox_FUND_SYM_PATTERNSValueChanged, true);
            app.ListBox_FUND_SYM_PATTERNS.Value = 'NULL';
            app.TAB_COMP_THINKING = uitab(app.TabGroup);
            app.TAB_COMP_THINKING.Title = 'CT';
            app.DEMOSLabel = uilabel(app.TAB_COMP_THINKING);
            app.DEMOSLabel.Text = 'DEMOS';
            app.DiscreteKnob_DEMO_WHEEL_CT = uiknob(app.TAB_COMP_THINKING, 'discrete');
            app.DiscreteKnob_DEMO_WHEEL_CT.Items = {'Off', 'SMD_EOM', 'Automate_EOM', '4_LINK_EOM', 'HELLO_ROBOT'};
            app.DiscreteKnob_DEMO_WHEEL_CT.ValueChangedFcn = createCallbackFcn(app, @DiscreteKnob_DEMO_WHEEL_CTValueChanged, true);
            app.Button_CT_PRES = uibutton(app.TAB_COMP_THINKING, 'push');
            app.Button_CT_PRES.ButtonPushedFcn = createCallbackFcn(app, @Button_CT_PRESPushed, true);
            app.Button_CT_PRES.Text = 'The CT pres';
            app.Button_CT_RIGID_PRES = uibutton(app.TAB_COMP_THINKING, 'push');
            app.Button_CT_RIGID_PRES.ButtonPushedFcn = createCallbackFcn(app, @Button_CT_RIGID_PRESPushed, true);
            app.Button_CT_RIGID_PRES.Text = 'The CT Rigid pres';
            app.Tab_TRICKS = uitab(app.TabGroup);
            app.Tab_TRICKS.Title = 'TRICKS';
            app.DiscreteKnob_DEMO_WHEEL_TRICKS = uiknob(app.Tab_TRICKS, 'discrete');
            app.DiscreteKnob_DEMO_WHEEL_TRICKS.Items = {'Off', 'Swingup_explore', 'Swingup_proto_model', 'Balance_explore', 'Balance_proto_model', 'ROBOT_Balance_model'};
            app.DiscreteKnob_DEMO_WHEEL_TRICKS.ValueChangedFcn = createCallbackFcn(app, @DiscreteKnob_DEMO_WHEEL_TRICKSValueChanged, true);
            app.LINK_NPTab = uitab(app.TabGroup);
            app.LINK_NPTab.Title = '2_LINK_NP';
            app.Knob_2dof_NP = uiknob(app.LINK_NPTab, 'discrete');
            app.Knob_2dof_NP.Items = {'Off', 'Lagrange_EOM', 'Model_Lagrange', 'Controlled', 'ElectroMech_System'};
            app.Knob_2dof_NP.ValueChangedFcn = createCallbackFcn(app, @Knob_2dof_NPValueChanged, true);
            app.TextArea2 = uitextarea(app.UIFigure_ROBX_DEMO_SELECTOR);
            app.TextArea2.Value = {'Robotics Explorer DEMO selector'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = bh_robx_demo_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure_ROBX_DEMO_SELECTOR)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure_ROBX_DEMO_SELECTOR)
        end
    end
end